classdef wave_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaveMonitoringApplicationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SENSORPLOTTINGTab               matlab.ui.container.Tab
        sensoroutputgraph               matlab.ui.control.UIAxes
        PlotPressureSensorDataButton    matlab.ui.control.Button
        PlotIMUSensorDataButton         matlab.ui.control.Button
        NumberofpeaksdetectedEditFieldLabel  matlab.ui.control.Label
        NumberofpeaksdetectedEditField  matlab.ui.control.NumericEditField
        TotalnumberofdataEditFieldLabel  matlab.ui.control.Label
        TotalnumberofdataEditField      matlab.ui.control.NumericEditField
        MINDANAORENEWABLERDCENTERLabel  matlab.ui.control.Label
        LivePlotPressureSensorDataButton  matlab.ui.control.Button
        LivePlotIMUSensorDataButton     matlab.ui.control.Button
        ANNPLOTTINGTab                  matlab.ui.container.Tab
        annoutputgraph                  matlab.ui.control.UIAxes
        TrainANNButton                  matlab.ui.control.Button
        PlotWaveModelOutputButton       matlab.ui.control.Button
        TotalnumberofdataEditField_3Label  matlab.ui.control.Label
        TotalnumberofdataEditField_3    matlab.ui.control.NumericEditField
        MINDANAORENEWABLERDCENTERLabel_3  matlab.ui.control.Label
        LivePlotWaveModelOutputButton   matlab.ui.control.Button
        ALGORITHMTab                    matlab.ui.container.Tab
        wavealgograph                   matlab.ui.control.UIAxes
        CalculateWaveParametersButton   matlab.ui.control.Button
        TotalnumberofdataEditField_4Label  matlab.ui.control.Label
        TotalnumberofdataEditField_4    matlab.ui.control.NumericEditField
        PlotDatafromWaveAlgorithmButton  matlab.ui.control.Button
        LivePlotDatafromWaveAlgorithmButton  matlab.ui.control.Button
        MINDANAORENEWABLERDCENTERLabel_4  matlab.ui.control.Label
        DATABASETab                     matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        DisplaySensorDataButton         matlab.ui.control.Button
        DisplayWaveDataButton           matlab.ui.control.Button
        ANNParametersButton             matlab.ui.control.Button
        DataGatheringLabel              matlab.ui.control.Label
        TotalnumberofdataEditField_2Label  matlab.ui.control.Label
        TotalnumberofdataEditField_2    matlab.ui.control.NumericEditField
        MINDANAORENEWABLERDCENTERLabel_2  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: PlotPressureSensorDataButton
        function PlotPressureSensorDataButtonPushed(app, event)
            %conn = database('wave_network','root','Jesusaann12.');
            %curs = exec(conn, 'SELECT * FROM sql_wavenetwork.sensor_data');
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM sensor_data;");
            curs = fetch(curs);
            data = curs.Data;
            %cell2numdata = cell2mat(data(:,3:10));
            cell2numdata = data{:,3:10}; %accelerometer to gyroscope
            %cell2numdata = data{:,3:13}; %accelerometer to magnetometer
            
            %datasize = size(data);
            %datalength = datasize(1,:);
                
            plot(app.sensoroutputgraph,cell2numdata(:,1),cell2numdata(:,2)); 
            title(app.sensoroutputgraph, 'Pressure Sensor Data')
            xlabel(app.sensoroutputgraph, 'Time (s)')
            ylabel(app.sensoroutputgraph, 'Water Level')
            legend(app.sensoroutputgraph, 'Water Level')
            
            peaks = findpeaks(cell2numdata(:,2));
            numpeaks = size(peaks);
            app.NumberofpeaksdetectedEditField.Value = numpeaks(1,1);
        
            numdata = size(data);
            app.TotalnumberofdataEditField.Value = numdata(1,1);
                   
        end

        % Button pushed function: TrainANNButton
        function TrainANNButtonPushed(app, event)
            nntool
        end

        % Button pushed function: DisplaySensorDataButton
        function DisplaySensorDataButtonPushed(app, event)
            %conn = database('wave_network','root','Jesusaann12.');
            %curs = exec(conn, 'SELECT * FROM sql_wavenetwork.sensor_data');
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM sensor_data;");
            curs = fetch(curs);
            data = curs.Data;
            %celldata2num = cell2mat(data(:,3:10));
               
            app.UITable.Data = data;
            set(app.UITable, 'ColumnName', {'Data No' 'Date and Time' 'Timer' 'Water Level' 'Acce x' 'Acce y' 'Acce z' 'Gyro x' 'Gyro y' 'Gyro z'});
            %set(app.UITable, 'ColumnName', {'Data No' 'Date and Time' 'Timer' 'Water Level' 'Acce x' 'Acce y' 'Acce z' 'Gyro x' 'Gyro y' 'Gyro z' 'Magne x' 'Magne y' 'Magne z'});
            set (app.UITable,'ColumnWidth', {80,140,85,85,80,80,80,80,80,80})
            %set (app.UITable,'ColumnWidth', {80,140,85,85,80,80,80,80,80,80,80,80,80})
            numdata = size(data);
            app.TotalnumberofdataEditField_2.Value = numdata(1,1);
            
            mh = msgbox('Operation Completed');
            th = findall(mh, 'Type', 'Text');      
            set(th, 'FontName', 'UD Digi Kyokasho NP-B');
        end

        % Button pushed function: PlotIMUSensorDataButton
        function PlotIMUSensorDataButtonPushed(app, event)
            %conn = database('wave_network','root','Jesusaann12.');
            %curs = exec(conn, 'SELECT * FROM sql_wavenetwork.sensor_data');
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM sensor_data;");
            curs = fetch(curs);
            data = curs.Data;
            %cell2numdata = cell2mat(data(:,3:10));
            cell2numdata = data{:,3:10}; %accelerometer to gyroscope only
            %cell2numdata = data{:,3:13}; %accelerometer to magnetometer
                        
            plot(app.sensoroutputgraph,cell2numdata(:,1),cell2numdata(:,3),cell2numdata(:,1),cell2numdata(:,4),cell2numdata(:,1),cell2numdata(:,5),cell2numdata(:,1),cell2numdata(:,6),cell2numdata(:,1),cell2numdata(:,7),cell2numdata(:,1),cell2numdata(:,8));
            %plot(app.sensoroutputgraph,cell2numdata(:,1),cell2numdata(:,3),cell2numdata(:,1),cell2numdata(:,4),cell2numdata(:,1),cell2numdata(:,5),cell2numdata(:,1),cell2numdata(:,6),cell2numdata(:,1),cell2numdata(:,7),cell2numdata(:,1),cell2numdata(:,8),cell2numdata(:,1),cell2numdata(:,9),cell2numdata(:,1),cell2numdata(:,10),cell2numdata(:,1),cell2numdata(:,11));
            title(app.sensoroutputgraph, 'Inertial Measurement Unit Output')
            xlabel(app.sensoroutputgraph, 'Time')
            ylabel(app.sensoroutputgraph, 'IMU Inputs')
            legend(app.sensoroutputgraph, {'Accelerometer (x)', 'Accelerometer (y)', 'Accelerometer (z)','Gyroscope (x)','Gyroscope (y)','Gyroscope (z)'})
            %legend(app.sensoroutputgraph, {'Accelerometer (x)', 'Accelerometer (y)', 'Accelerometer (z)','Gyroscope (x)','Gyroscope (y)','Gyroscope (z)','Magnetometer (x)','Magnetometer (y)','Magnetometer (z)'})
            numdata = size(data);
            app.TotalnumberofdataEditField.Value = numdata(1,1);
            app.NumberofpeaksdetectedEditField.Value = 0;
        end

        % Button pushed function: PlotWaveModelOutputButton
        function PlotWaveModelOutputButtonPushed(app, event)
           
        end

        % Button pushed function: DisplayWaveDataButton
        function DisplayWaveDataButtonPushed(app, event)
            %conn = database('wave_network','root','Jesusaann12.');
            %curs = exec(conn, 'SELECT * FROM sql_wavenetwork.wave_data');
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM wave_data;");
            curs = fetch(curs);
            data = curs.Data;
            %celldata2num = cell2mat(data(:,3:10));
               
            app.UITable.Data = data;
            set(app.UITable, 'ColumnName', {'Data No' 'Date and Time' 'Wave Period' 'Wave Height' 'Acce x' 'Acce y' 'Acce z' 'Gyro x' 'Gyro y' 'Gyro z'});
            set (app.UITable,'ColumnWidth', {80,140,85,85,80,80,80,80,80,80})
            numdata = size(data);
            app.TotalnumberofdataEditField_2.Value = numdata(1,1);
            mh = msgbox('Operation Completed');
            th = findall(mh, 'Type', 'Text');      
            set(th, 'FontName', 'UD Digi Kyokasho NP-B');
        end

        % Button pushed function: LivePlotPressureSensorDataButton
        function LivePlotPressureSensorDataButtonPushed(app, event)
            %conn = database('wave_network','root','Jesusaann12.');
            %curs = exec(conn, 'SELECT * FROM sql_wavenetwork.sensor_data');
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM sensor_data;");
            curs = fetch(curs);
            data = curs.Data;
            %cell2numdata = cell2mat(data(:,3:10));
            cell2numdata = data{:,3:10};
            %datasize = size(data);
            %datalength = datasize(1,:);
            
            peaks = findpeaks(cell2numdata(:,2));
            numpeaks = size(peaks);
            app.NumberofpeaksdetectedEditField.Value = numpeaks(1,1);
        
            numdata = size(data);
            app.TotalnumberofdataEditField.Value = numdata(1,1);
            
            a = length(cell2numdata);
            
            title(app.sensoroutputgraph, 'Pressure Sensor Data')
            xlabel(app.sensoroutputgraph, 'Time (s)')
            ylabel(app.sensoroutputgraph, 'Water Level')
            
                for n = 1:1:a
                    if n < a;
                        plot(app.sensoroutputgraph,cell2numdata(1:n,1),cell2numdata(1:n,2),'b','LineWidth',2);
                        legend(app.sensoroutputgraph, 'Water Level')
                        drawnow
                        pause(0.5)
                    else 
                        if n > a;
                            break;
                        end
                    end
                end    
        end

        % Button pushed function: LivePlotIMUSensorDataButton
        function LivePlotIMUSensorDataButtonPushed(app, event)
            %conn = database('wave_network','root','Jesusaann12.');
            %curs = exec(conn, 'SELECT * FROM sql_wavenetwork.sensor_data');
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM sensor_data;");
            curs = fetch(curs);
            data = curs.Data;
            %cell2numdata = cell2mat(data(:,3:10));
            cell2numdata = data{:,3:10};
            numdata = size(data);
            app.TotalnumberofdataEditField.Value = numdata(1,1); 
            app.NumberofpeaksdetectedEditField.Value = 0;
            
           
            title(app.sensoroutputgraph, 'Inertial Measurement Unit Output')
            xlabel(app.sensoroutputgraph, 'Time')
            ylabel(app.sensoroutputgraph, 'IMU Inputs')
            
            a = length(cell2numdata);
            
            for n = 1:1:a
                    if n < a;
                        plot(app.sensoroutputgraph,cell2numdata(1:n,1),cell2numdata(1:n,3),cell2numdata(1:n,1),cell2numdata(1:n,4),cell2numdata(1:n,1),cell2numdata(1:n,5),cell2numdata(1:n,1),cell2numdata(1:n,6),cell2numdata(1:n,1),cell2numdata(1:n,7),cell2numdata(1:n,1),cell2numdata(1:n,8),'LineWidth',2);
                        legend(app.sensoroutputgraph, {'Accelerometer (x)', 'Accelerometer (y)', 'Accelerometer (z)','Gyroscope (x)','Gyroscope (y)','Gyroscope (z)'})
                        drawnow
                        pause(0.5)
                    else 
                        if n > a;
                            break;
                        end
                    end
            end    
            
        end

        % Button pushed function: CalculateWaveParametersButton
        function CalculateWaveParametersButtonPushed(app, event)
        %mg = msgbox('Wave Algorithm is running please wait.','Message','warn');
        %th = findall(mg, 'Type', 'Text');      
        %set(th, 'FontName', 'UD Digi Kyokasho NP-B');
            
        conn = database('wave_network','','');
        sqlquery1 ="DELETE FROM wave_data;";
        deletecurs = exec(conn, sqlquery1);
        close(deletecurs);
        sqlquery2 = "DELETE FROM SQLITE_SEQUENCE WHERE name='wave_data';";
        truncatecurs = exec(conn, sqlquery2);
        close(truncatecurs);

        % deletequery = 'DELETE * FROM sql_wavenetwork.wave_data';
        % execute(conn, deletequery)

        curs = exec(conn, "SELECT * FROM sensor_data;");
        curs = fetch(curs);
        data = curs.Data;
        cell2numdata = data{:,3:10};

        waterlevel = cell2numdata(:,2);
        [pks,locs] = findpeaks(waterlevel);
        datetime = data{locs,2};
        waveheight = data{locs,4};
        imu = data{locs,5:10};
        accex = imu(:,1);
        accey = imu(:,2);
        accez = imu(:,3);
        gyrox = imu(:,4);
        gyroy = imu(:,5);
        gyroz = imu(:,6);
        peaktime = data{locs,3};

        a = length(peaktime);
            for n = 1:1:a
                if n < a;
                    if mod(a,2) ~=0;
                        res(n,:) = [peaktime(n+1,1)-peaktime(n,1)];
                        waveperiod = [zeros(1); res]
                
                        if n == 1;
                            tablename = "wave_data";
                            data1 = table(datetime(n,:),waveperiod(n,:),waveheight(n,:),accex(n,:),accey(n,:),accez(n,:),gyrox(n,:),gyroy(n,:),gyroz(n,:),'VariableNames',{'date_and_time' 'wave_period' 'wave_height' 'acce_x' 'acce_y' 'acce_z' 'gyro_x' 'gyro_y' 'gyro_z'});
                            sqlwrite(conn,tablename,data1)
                    
                            data2 = table(datetime(n+1,:),waveperiod(n+1,:),waveheight(n+1,:),accex(n+1,:),accey(n+1,:),accez(n+1,:),gyrox(n+1,:),gyroy(n+1,:),gyroz(n+1,:),'VariableNames',{'date_and_time' 'wave_period' 'wave_height' 'acce_x' 'acce_y' 'acce_z' 'gyro_x' 'gyro_y' 'gyro_z'});
                            sqlwrite(conn,tablename,data2)
                
                        else
                            tablename = "wave_data";
                            data1 = table(datetime(n+1,:),waveperiod(n+1,:),waveheight(n+1,:),accex(n+1,:),accey(n+1,:),accez(n+1,:),gyrox(n+1,:),gyroy(n+1,:),gyroz(n+1,:),'VariableNames',{'date_and_time' 'wave_period' 'wave_height' 'acce_x' 'acce_y' 'acce_z' 'gyro_x' 'gyro_y' 'gyro_z'});
                            sqlwrite(conn,tablename,data1)
                            
                        end
                
                    else mod(a,2) ==0;
                        res(n,:) = [peaktime(n+1,1)-peaktime(n,1)];
                        waveperiod = [zeros(1); res]
                
                        if n == 1;
                            tablename = "wave_data";
                            data1 = table(datetime(n,:),waveperiod(n,:),waveheight(n,:),accex(n,:),accey(n,:),accez(n,:),gyrox(n,:),gyroy(n,:),gyroz(n,:),'VariableNames',{'date_and_time' 'wave_period' 'wave_height' 'acce_x' 'acce_y' 'acce_z' 'gyro_x' 'gyro_y' 'gyro_z'});
                            sqlwrite(conn,tablename,data1)
                    
                            data2 = table(datetime(n+1,:),waveperiod(n+1,:),waveheight(n+1,:),accex(n+1,:),accey(n+1,:),accez(n+1,:),gyrox(n+1,:),gyroy(n+1,:),gyroz(n+1,:),'VariableNames',{'date_and_time' 'wave_period' 'wave_height' 'acce_x' 'acce_y' 'acce_z' 'gyro_x' 'gyro_y' 'gyro_z'});
                            sqlwrite(conn,tablename,data2)
                
                        else
                            tablename = "wave_data";
                            data1 = table(datetime(n+1,:),waveperiod(n+1,:),waveheight(n+1,:),accex(n+1,:),accey(n+1,:),accez(n+1,:),gyrox(n+1,:),gyroy(n+1,:),gyroz(n+1,:),'VariableNames',{'date_and_time' 'wave_period' 'wave_height' 'acce_x' 'acce_y' 'acce_z' 'gyro_x' 'gyro_y' 'gyro_z'});
                            sqlwrite(conn,tablename,data1)
                            
                        end
                
                    end
                else
                    if n > a;
                        close(conn)
                        break
                    end
                end
            end
            
            LivePlotDatafromWaveAlgorithmButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
            
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM wave_data;");
            curs = fetch(curs);
            data3 = curs.Data;
            %celldata2num = cell2mat(data(:,3:10));
               
            app.UITable.Data = data3;
            set(app.UITable, 'ColumnName', {'Data No' 'Date and Time' 'Wave Period' 'Wave Height' 'Acce x' 'Acce y' 'Acce z' 'Gyro x' 'Gyro y' 'Gyro z'});
            set (app.UITable,'ColumnWidth', {80,140,85,85,80,80,80,80,80,80})
            numdata = size(data3);
            app.TotalnumberofdataEditField_2.Value = numdata(1,1);
            
            %mh = msgbox('Wave data calculations are done.');
            %th = findall(mh, 'Type', 'Text');      
            %set(th, 'FontName', 'UD Digi Kyokasho NP-B');
        end

        % Button pushed function: LivePlotDatafromWaveAlgorithmButton
        function LivePlotDatafromWaveAlgorithmButtonPushed(app, event)
            %conn = database('wave_network','root','Jesusaann12.');
            %curs = exec(conn, 'SELECT * FROM sql_wavenetwork.sensor_data');
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM wave_data;");
            curs = fetch(curs);
            data = curs.Data;
            %cell2numdata = cell2mat(data(:,3:10));
            cell2numdata = data{:,3:10};
            %datasize = size(data);
            %datalength = datasize(1,:);
            
            %peaks = findpeaks(cell2numdata(:,2));
            %numpeaks = size(peaks);
            %app.NumberofpeaksdetectedEditField_4.Value = numpeaks(1,1);
        
            numdata = size(data);
            app.TotalnumberofdataEditField_4.Value = numdata(1,1);
            
            a = length(cell2numdata);
            
            title(app.wavealgograph, 'Wave Algorithm')
            xlabel(app.wavealgograph, 'Wave Period')
            ylabel(app.wavealgograph, 'Wave Height')
            
                for n = 1:1:a
                    if n < a;
                        stem(app.wavealgograph,cell2numdata(1:n,1),cell2numdata(1:n,2),'b','LineWidth',2);
                        drawnow
                        pause(0.5)
                    else 
                        if n > a;
                            break;
                        end
                    end
                end    
        end

        % Button pushed function: PlotDatafromWaveAlgorithmButton
        function PlotDatafromWaveAlgorithmButtonPushed(app, event)
            %conn = database('wave_network','root','Jesusaann12.');
            %curs = exec(conn, 'SELECT * FROM sql_wavenetwork.sensor_data');
            conn = database('wave_network','','');
            curs = exec(conn, "SELECT * FROM wave_data;");
            curs = fetch(curs);
            data = curs.Data;
            %cell2numdata = cell2mat(data(:,3:10));
            cell2numdata = data{:,3:10};
            %datasize = size(data);
            %datalength = datasize(1,:);
                
            stem(app.wavealgograph,cell2numdata(:,1),cell2numdata(:,2)); 
            title(app.wavealgograph, 'Wave Algorithm')
            xlabel(app.wavealgograph, 'Wave Period')
            ylabel(app.wavealgograph, 'Wave Height')
            
            %peaks = findpeaks(cell2numdata(:,2));
            %numpeaks = size(peaks);
            %app.NumberofpeaksdetectedEditField.Value = numpeaks(1,1);
        
            numdata = size(data);
            app.TotalnumberofdataEditField_4.Value = numdata(1,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaveMonitoringApplicationUIFigure = uifigure;
            app.WaveMonitoringApplicationUIFigure.Name = 'Wave Monitoring Application';
            app.TabGroup = uitabgroup(app.WaveMonitoringApplicationUIFigure);
            app.SENSORPLOTTINGTab = uitab(app.TabGroup);
            app.SENSORPLOTTINGTab.Title = 'SENSOR PLOTTING';
            app.sensoroutputgraph = uiaxes(app.SENSORPLOTTINGTab);
            title(app.sensoroutputgraph, 'Sensor Output')
            xlabel(app.sensoroutputgraph, 'Time')
            ylabel(app.sensoroutputgraph, 'Sensor Reading')
            app.PlotPressureSensorDataButton = uibutton(app.SENSORPLOTTINGTab, 'push');
            app.PlotPressureSensorDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPressureSensorDataButtonPushed, true);
            app.PlotPressureSensorDataButton.Text = 'Plot Pressure Sensor Data';
            app.PlotIMUSensorDataButton = uibutton(app.SENSORPLOTTINGTab, 'push');
            app.PlotIMUSensorDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIMUSensorDataButtonPushed, true);
            app.PlotIMUSensorDataButton.Text = 'Plot IMU Sensor Data';
            app.NumberofpeaksdetectedEditFieldLabel = uilabel(app.SENSORPLOTTINGTab);
            app.NumberofpeaksdetectedEditFieldLabel.Text = 'Number of peaks detected:';
            app.NumberofpeaksdetectedEditField = uieditfield(app.SENSORPLOTTINGTab, 'numeric');
            app.NumberofpeaksdetectedEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpeaksdetectedEditField.Editable = 'off';
            app.TotalnumberofdataEditFieldLabel = uilabel(app.SENSORPLOTTINGTab);
            app.TotalnumberofdataEditFieldLabel.Text = 'Total number of data:';
            app.TotalnumberofdataEditField = uieditfield(app.SENSORPLOTTINGTab, 'numeric');
            app.TotalnumberofdataEditField.ValueDisplayFormat = '%.0f';
            app.TotalnumberofdataEditField.Editable = 'off';
            app.MINDANAORENEWABLERDCENTERLabel = uilabel(app.SENSORPLOTTINGTab);
            app.MINDANAORENEWABLERDCENTERLabel.Text = '© 2021 MINDANAO RENEWABLE R&D CENTER';
            app.LivePlotPressureSensorDataButton = uibutton(app.SENSORPLOTTINGTab, 'push');
            app.LivePlotPressureSensorDataButton.ButtonPushedFcn = createCallbackFcn(app, @LivePlotPressureSensorDataButtonPushed, true);
            app.LivePlotPressureSensorDataButton.Text = 'Live Plot Pressure Sensor Data';
            app.LivePlotIMUSensorDataButton = uibutton(app.SENSORPLOTTINGTab, 'push');
            app.LivePlotIMUSensorDataButton.ButtonPushedFcn = createCallbackFcn(app, @LivePlotIMUSensorDataButtonPushed, true);
            app.LivePlotIMUSensorDataButton.Text = 'Live Plot IMU Sensor Data';
            app.ANNPLOTTINGTab = uitab(app.TabGroup);
            app.ANNPLOTTINGTab.Title = 'ANN PLOTTING';
            app.annoutputgraph = uiaxes(app.ANNPLOTTINGTab);
            title(app.annoutputgraph, 'Artificial Neural Network Output')
            xlabel(app.annoutputgraph, 'Wave Period')
            ylabel(app.annoutputgraph, 'Wave Height')
            app.TrainANNButton = uibutton(app.ANNPLOTTINGTab, 'push');
            app.TrainANNButton.ButtonPushedFcn = createCallbackFcn(app, @TrainANNButtonPushed, true);
            app.TrainANNButton.Text = 'Train ANN';
            app.PlotWaveModelOutputButton = uibutton(app.ANNPLOTTINGTab, 'push');
            app.PlotWaveModelOutputButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWaveModelOutputButtonPushed, true);
            app.PlotWaveModelOutputButton.Text = 'Plot Wave Model Output';
            app.TotalnumberofdataEditField_3Label = uilabel(app.ANNPLOTTINGTab);
            app.TotalnumberofdataEditField_3Label.Text = 'Total number of data:';
            app.TotalnumberofdataEditField_3 = uieditfield(app.ANNPLOTTINGTab, 'numeric');
            app.TotalnumberofdataEditField_3.ValueDisplayFormat = '%.0f';
            app.MINDANAORENEWABLERDCENTERLabel_3 = uilabel(app.ANNPLOTTINGTab);
            app.MINDANAORENEWABLERDCENTERLabel_3.Text = '© 2021 MINDANAO RENEWABLE R&D CENTER';
            app.LivePlotWaveModelOutputButton = uibutton(app.ANNPLOTTINGTab, 'push');
            app.LivePlotWaveModelOutputButton.Text = 'Live Plot Wave Model Output';
            app.ALGORITHMTab = uitab(app.TabGroup);
            app.ALGORITHMTab.Title = 'ALGORITHM';
            app.wavealgograph = uiaxes(app.ALGORITHMTab);
            title(app.wavealgograph, 'Wave Algorithm')
            xlabel(app.wavealgograph, 'Wave Period')
            ylabel(app.wavealgograph, 'Wave Height')
            app.CalculateWaveParametersButton = uibutton(app.ALGORITHMTab, 'push');
            app.CalculateWaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateWaveParametersButtonPushed, true);
            app.CalculateWaveParametersButton.Text = 'Calculate Wave Parameters ';
            app.TotalnumberofdataEditField_4Label = uilabel(app.ALGORITHMTab);
            app.TotalnumberofdataEditField_4Label.Text = 'Total number of data:';
            app.TotalnumberofdataEditField_4 = uieditfield(app.ALGORITHMTab, 'numeric');
            app.TotalnumberofdataEditField_4.ValueDisplayFormat = '%.0f';
            app.TotalnumberofdataEditField_4.Editable = 'off';
            app.PlotDatafromWaveAlgorithmButton = uibutton(app.ALGORITHMTab, 'push');
            app.PlotDatafromWaveAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDatafromWaveAlgorithmButtonPushed, true);
            app.PlotDatafromWaveAlgorithmButton.Text = 'Plot Data from Wave Algorithm';
            app.LivePlotDatafromWaveAlgorithmButton = uibutton(app.ALGORITHMTab, 'push');
            app.LivePlotDatafromWaveAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @LivePlotDatafromWaveAlgorithmButtonPushed, true);
            app.LivePlotDatafromWaveAlgorithmButton.Text = 'Live Plot Data from Wave Algorithm';
            app.MINDANAORENEWABLERDCENTERLabel_4 = uilabel(app.ALGORITHMTab);
            app.MINDANAORENEWABLERDCENTERLabel_4.Text = '© 2021 MINDANAO RENEWABLE R&D CENTER';
            app.DATABASETab = uitab(app.TabGroup);
            app.DATABASETab.Title = 'DATABASE';
            app.UITable = uitable(app.DATABASETab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.DisplaySensorDataButton = uibutton(app.DATABASETab, 'push');
            app.DisplaySensorDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySensorDataButtonPushed, true);
            app.DisplaySensorDataButton.Text = 'Display Sensor Data';
            app.DisplayWaveDataButton = uibutton(app.DATABASETab, 'push');
            app.DisplayWaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayWaveDataButtonPushed, true);
            app.DisplayWaveDataButton.Text = 'Display Wave Data';
            app.ANNParametersButton = uibutton(app.DATABASETab, 'push');
            app.ANNParametersButton.Text = 'ANN Parameters';
            app.DataGatheringLabel = uilabel(app.DATABASETab);
            app.DataGatheringLabel.Text = 'Data Gathering';
            app.TotalnumberofdataEditField_2Label = uilabel(app.DATABASETab);
            app.TotalnumberofdataEditField_2Label.Text = 'Total number of data:';
            app.TotalnumberofdataEditField_2 = uieditfield(app.DATABASETab, 'numeric');
            app.TotalnumberofdataEditField_2.ValueDisplayFormat = '%.0f';
            app.TotalnumberofdataEditField_2.Editable = 'off';
            app.MINDANAORENEWABLERDCENTERLabel_2 = uilabel(app.DATABASETab);
            app.MINDANAORENEWABLERDCENTERLabel_2.Text = '© 2021 MINDANAO RENEWABLE R&D CENTER';
        end
    end

    methods (Access = public)

        % Construct app
        function app = wave_analyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaveMonitoringApplicationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaveMonitoringApplicationUIFigure)
        end
    end
end